classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button_left                     matlab.ui.control.StateButton
        Button_right                    matlab.ui.control.StateButton
        Button_down                     matlab.ui.control.StateButton
        Button_up                       matlab.ui.control.StateButton
        SynchronisedMotorMovementPanel  matlab.ui.container.Panel
        Slider_2                        matlab.ui.control.Slider
        MoveButton_4                    matlab.ui.control.Button
        MoveButton_3                    matlab.ui.control.Button
        YAxisEditField_2                matlab.ui.control.NumericEditField
        YAxisEditField_2Label           matlab.ui.control.Label
        XAxisEditField_2                matlab.ui.control.NumericEditField
        XAxisEditField_2Label           matlab.ui.control.Label
        YAxisEditField                  matlab.ui.control.NumericEditField
        YAxisEditFieldLabel             matlab.ui.control.Label
        XAxisEditField                  matlab.ui.control.NumericEditField
        XAxisEditFieldLabel             matlab.ui.control.Label
        AbsoluteMovementLabel_2         matlab.ui.control.Label
        RelativeMovementLabel_2         matlab.ui.control.Label
        SpeedEditField_2                matlab.ui.control.NumericEditField
        SpeedEditField_2Label           matlab.ui.control.Label
        CustomCommandPanel              matlab.ui.container.Panel
        SendButton                      matlab.ui.control.Button
        CommandEditField                matlab.ui.control.EditField
        CommandEditFieldLabel           matlab.ui.control.Label
        IndependentMotorMovementPanel   matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
        MoveButton_9                    matlab.ui.control.Button
        MoveButton_8                    matlab.ui.control.Button
        MoveButton_7                    matlab.ui.control.Button
        MoveButton_6                    matlab.ui.control.Button
        MoveButton_5                    matlab.ui.control.Button
        SpeedEditField                  matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
        MoveButton                      matlab.ui.control.Button
        MotorCEditField_2               matlab.ui.control.NumericEditField
        MotorCEditField_2Label          matlab.ui.control.Label
        MotorBEditField_2               matlab.ui.control.NumericEditField
        MotorBEditField_2Label          matlab.ui.control.Label
        MotorAEditField_2               matlab.ui.control.NumericEditField
        MotorAEditField_2Label          matlab.ui.control.Label
        AbsoluteMovementLabel           matlab.ui.control.Label
        RelativeMovementLabel           matlab.ui.control.Label
        MotorCEditField                 matlab.ui.control.NumericEditField
        MotorCEditFieldLabel            matlab.ui.control.Label
        MotorBEditField                 matlab.ui.control.NumericEditField
        MotorBEditFieldLabel            matlab.ui.control.Label
        MotorAEditField                 matlab.ui.control.NumericEditField
        MotorAEditFieldLabel            matlab.ui.control.Label
        MotorStatusPanel                matlab.ui.container.Panel
        MovetoHomeButton                matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        MotorsOFFButton                 matlab.ui.control.Button
        MotorsONButton                  matlab.ui.control.Button
        ArduinoPanel                    matlab.ui.container.Panel
        SelectPortDropDown              matlab.ui.control.DropDown
        SelectPortDropDownLabel         matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        BaudRateEditField               matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel          matlab.ui.control.Label
        EnterPortEditField              matlab.ui.control.EditField
        EnterPortEditFieldLabel         matlab.ui.control.Label
        BEATRIXGUILabel                 matlab.ui.control.Label
    end

        
    properties (Access = private)
        ArduinoObj = serialport;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            app.ArduinoObj = serialport(string(app.EnterPortEditField.Value), app.BaudRateEditField.Value); %Note: baud rate should be 230400
            configureTerminator(app.ArduinoObj,"CR"); % Configure port to carriage return
            flush(app.ArduinoObj); % Flush old serial port data
        end

        % Button pushed function: MotorsONButton
        function MotorsONButtonPushed(app, event)
            writeline(app.ArduinoObj, "@ENMOTORS ON")
        end

        % Button pushed function: MotorsOFFButton
        function MotorsOFFButtonPushed(app, event)
            writeline(app.ArduinoObj, "@ENMOTORS OFF")
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            writeline(app.ArduinoObj, "@CALNOW")
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            writeline(app.ArduinoObj, app.CommandEditField.Value)
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            writeline(app.ArduinoObj, "@MOVRX " + string(app.MotorAEditField.Value) + " " + string(app.SpeedEditField.Value))
        end

        % Button pushed function: MoveButton_5
        function MoveButton_5Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVRY " + string(app.MotorBEditField.Value) + " " + string(app.SpeedEditField.Value))
        end

        % Button pushed function: MoveButton_6
        function MoveButton_6Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVRZ " + string(app.MotorCEditField.Value) + " " + string(app.SpeedEditField.Value))
        end

        % Button pushed function: MoveButton_7
        function MoveButton_7Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVAX " + string(app.MotorAEditField_2.Value) + " " + string(app.SpeedEditField.Value))
        end

        % Button pushed function: MoveButton_8
        function MoveButton_8Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVAY " + string(app.MotorBEditField_2.Value) + " " + string(app.SpeedEditField.Value))
        end

        % Button pushed function: MoveButton_9
        function MoveButton_9Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVAZ " + string(app.MotorCEditField_2.Value) + " " + string(app.SpeedEditField.Value))
        end

        % Button pushed function: MoveButton_3
        function MoveButton_3Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVTORPOS " + string(app.XAxisEditField.Value) + " " + string(app.YAxisEditField.Value) + " " + string(app.SpeedEditField_2.Value))
        end

        % Button pushed function: MoveButton_4
        function MoveButton_4Pushed(app, event)
            writeline(app.ArduinoObj, "@MOVTOAPOS " + string(app.XAxisEditField_2.Value) + " " + string(app.YAxisEditField_2.Value) + " " + string(app.SpeedEditField_2.Value))
        end

        % Drop down opening function: SelectPortDropDown
        function SelectPortDropDownOpening(app, event)
            %app.SelectPortDropDown.Items = serialportlist();
        end

        % Clicked callback: SelectPortDropDown
        function SelectPortDropDownClicked(app, event)
            %item = event.InteractionInformation.Item;
            %app.EnterPortEditField.Value = app.SelectPortDropDown.Items(item);
        end

        % Button pushed function: MovetoHomeButton
        function MovetoHomeButtonPushed(app, event)
            writeline(app.ArduinoObj, "@MOVHOME")
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value; % Change speed box to match slider
            app.SpeedEditField.Value = value;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value; % Change speed box to match slider
            app.SpeedEditField_2.Value = value;
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            value = app.SpeedEditField.Value; % Change slider to match speed box
            app.Slider.Value = value;
        end

        % Value changed function: SpeedEditField_2
        function SpeedEditField_2ValueChanged(app, event)
            value = app.SpeedEditField_2.Value; % Change slider to match speed box
            app.Slider_2.Value = value;
        end

        % Value changed function: Button_up
        function Button_upValueChanged(app, event)
            value = app.Button_up.Value;
            if value == 1
                writeline(app.ArduinoObj, "@MOVTORPOS 0 -800 100")
            else
                writeline(app.ArduinoObj, "@STOPALL")
            end
        end

        % Value changed function: Button_left
        function Button_leftValueChanged(app, event)
            value = app.Button_left.Value;
            if value == 1
                writeline(app.ArduinoObj, "@MOVTORPOS -1000 0 100")
            else
                writeline(app.ArduinoObj, "@STOPALL")
            end
        end

        % Value changed function: Button_right
        function Button_rightValueChanged(app, event)
            value = app.Button_right.Value;
            if value == 1
                writeline(app.ArduinoObj, "@MOVTORPOS 0 -800 100")
            else
                writeline(app.ArduinoObj, "@STOPALL")  
            end
        end

        % Value changed function: Button_down
        function Button_downValueChanged(app, event)
            value = app.Button_down.Value;
            if value == 1
                writeline(app.ArduinoObj, "@MOVTORPOS 0 800 100")
            else
                writeline(app.ArduinoObj, "@STOPALL")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BEATRIXGUILabel = uilabel(app.UIFigure);
            app.BEATRIXGUILabel.Text = 'BEATRIX GUI';
            app.ArduinoPanel = uipanel(app.UIFigure);
            app.ArduinoPanel.Title = 'Arduino';
            app.EnterPortEditFieldLabel = uilabel(app.ArduinoPanel);
            app.EnterPortEditFieldLabel.Text = 'Enter Port';
            app.EnterPortEditField = uieditfield(app.ArduinoPanel, 'text');
            app.BaudRateEditFieldLabel = uilabel(app.ArduinoPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.ArduinoPanel, 'numeric');
            app.ConnectButton = uibutton(app.ArduinoPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SelectPortDropDownLabel = uilabel(app.ArduinoPanel);
            app.SelectPortDropDownLabel.Text = 'Select Port';
            app.SelectPortDropDown = uidropdown(app.ArduinoPanel);
            app.SelectPortDropDown.Items = {'Port', 'Option 2', 'Option 3', 'Option 4'};
            app.SelectPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectPortDropDownOpening, true);
            app.SelectPortDropDown.ClickedFcn = createCallbackFcn(app, @SelectPortDropDownClicked, true);
            app.SelectPortDropDown.Value = 'Port';
            app.MotorStatusPanel = uipanel(app.UIFigure);
            app.MotorStatusPanel.Title = 'Motor Status';
            app.MotorsONButton = uibutton(app.MotorStatusPanel, 'push');
            app.MotorsONButton.ButtonPushedFcn = createCallbackFcn(app, @MotorsONButtonPushed, true);
            app.MotorsONButton.Text = 'Motors ON';
            app.MotorsOFFButton = uibutton(app.MotorStatusPanel, 'push');
            app.MotorsOFFButton.ButtonPushedFcn = createCallbackFcn(app, @MotorsOFFButtonPushed, true);
            app.MotorsOFFButton.Text = 'Motors OFF';
            app.CalibrateButton = uibutton(app.MotorStatusPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.MovetoHomeButton = uibutton(app.MotorStatusPanel, 'push');
            app.MovetoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoHomeButtonPushed, true);
            app.MovetoHomeButton.Text = 'Move to Home';
            app.IndependentMotorMovementPanel = uipanel(app.UIFigure);
            app.IndependentMotorMovementPanel.Title = 'Independent Motor Movement';
            app.MotorAEditFieldLabel = uilabel(app.IndependentMotorMovementPanel);
            app.MotorAEditFieldLabel.Text = 'Motor A';
            app.MotorAEditField = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.MotorBEditFieldLabel = uilabel(app.IndependentMotorMovementPanel);
            app.MotorBEditFieldLabel.Text = 'Motor B';
            app.MotorBEditField = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.MotorCEditFieldLabel = uilabel(app.IndependentMotorMovementPanel);
            app.MotorCEditFieldLabel.Text = 'Motor C';
            app.MotorCEditField = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.RelativeMovementLabel = uilabel(app.IndependentMotorMovementPanel);
            app.RelativeMovementLabel.Text = 'Relative Movement';
            app.AbsoluteMovementLabel = uilabel(app.IndependentMotorMovementPanel);
            app.AbsoluteMovementLabel.Text = 'Absolute Movement';
            app.MotorAEditField_2Label = uilabel(app.IndependentMotorMovementPanel);
            app.MotorAEditField_2Label.Text = 'Motor A';
            app.MotorAEditField_2 = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.MotorBEditField_2Label = uilabel(app.IndependentMotorMovementPanel);
            app.MotorBEditField_2Label.Text = 'Motor B';
            app.MotorBEditField_2 = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.MotorCEditField_2Label = uilabel(app.IndependentMotorMovementPanel);
            app.MotorCEditField_2Label.Text = 'Motor C';
            app.MotorCEditField_2 = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.MoveButton = uibutton(app.IndependentMotorMovementPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.SpeedEditFieldLabel = uilabel(app.IndependentMotorMovementPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.IndependentMotorMovementPanel, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.MoveButton_5 = uibutton(app.IndependentMotorMovementPanel, 'push');
            app.MoveButton_5.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_5Pushed, true);
            app.MoveButton_5.Text = 'Move';
            app.MoveButton_6 = uibutton(app.IndependentMotorMovementPanel, 'push');
            app.MoveButton_6.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_6Pushed, true);
            app.MoveButton_6.Text = 'Move';
            app.MoveButton_7 = uibutton(app.IndependentMotorMovementPanel, 'push');
            app.MoveButton_7.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_7Pushed, true);
            app.MoveButton_7.Text = 'Move';
            app.MoveButton_8 = uibutton(app.IndependentMotorMovementPanel, 'push');
            app.MoveButton_8.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_8Pushed, true);
            app.MoveButton_8.Text = 'Move';
            app.MoveButton_9 = uibutton(app.IndependentMotorMovementPanel, 'push');
            app.MoveButton_9.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_9Pushed, true);
            app.MoveButton_9.Text = 'Move';
            app.Slider = uislider(app.IndependentMotorMovementPanel);
            app.Slider.Limits = [0 200];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Step = 1;
            app.CustomCommandPanel = uipanel(app.UIFigure);
            app.CustomCommandPanel.Title = 'Custom Command';
            app.CommandEditFieldLabel = uilabel(app.CustomCommandPanel);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.CustomCommandPanel, 'text');
            app.SendButton = uibutton(app.CustomCommandPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.SynchronisedMotorMovementPanel = uipanel(app.UIFigure);
            app.SynchronisedMotorMovementPanel.Title = 'Synchronised Motor Movement';
            app.SpeedEditField_2Label = uilabel(app.SynchronisedMotorMovementPanel);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.SpeedEditField_2 = uieditfield(app.SynchronisedMotorMovementPanel, 'numeric');
            app.SpeedEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpeedEditField_2ValueChanged, true);
            app.RelativeMovementLabel_2 = uilabel(app.SynchronisedMotorMovementPanel);
            app.RelativeMovementLabel_2.Text = 'Relative Movement';
            app.AbsoluteMovementLabel_2 = uilabel(app.SynchronisedMotorMovementPanel);
            app.AbsoluteMovementLabel_2.Text = 'Absolute Movement';
            app.XAxisEditFieldLabel = uilabel(app.SynchronisedMotorMovementPanel);
            app.XAxisEditFieldLabel.Text = 'X-Axis';
            app.XAxisEditField = uieditfield(app.SynchronisedMotorMovementPanel, 'numeric');
            app.YAxisEditFieldLabel = uilabel(app.SynchronisedMotorMovementPanel);
            app.YAxisEditFieldLabel.Text = 'Y-Axis';
            app.YAxisEditField = uieditfield(app.SynchronisedMotorMovementPanel, 'numeric');
            app.XAxisEditField_2Label = uilabel(app.SynchronisedMotorMovementPanel);
            app.XAxisEditField_2Label.Text = 'X-Axis';
            app.XAxisEditField_2 = uieditfield(app.SynchronisedMotorMovementPanel, 'numeric');
            app.YAxisEditField_2Label = uilabel(app.SynchronisedMotorMovementPanel);
            app.YAxisEditField_2Label.Text = 'Y-Axis';
            app.YAxisEditField_2 = uieditfield(app.SynchronisedMotorMovementPanel, 'numeric');
            app.MoveButton_3 = uibutton(app.SynchronisedMotorMovementPanel, 'push');
            app.MoveButton_3.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_3Pushed, true);
            app.MoveButton_3.Text = 'Move';
            app.MoveButton_4 = uibutton(app.SynchronisedMotorMovementPanel, 'push');
            app.MoveButton_4.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_4Pushed, true);
            app.MoveButton_4.Text = 'Move';
            app.Slider_2 = uislider(app.SynchronisedMotorMovementPanel);
            app.Slider_2.Limits = [0 200];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Step = 1;
            app.Button_up = uibutton(app.UIFigure, 'state');
            app.Button_up.ValueChangedFcn = createCallbackFcn(app, @Button_upValueChanged, true);
            app.Button_up.Text = '⬆';
            app.Button_down = uibutton(app.UIFigure, 'state');
            app.Button_down.ValueChangedFcn = createCallbackFcn(app, @Button_downValueChanged, true);
            app.Button_down.Text = '⬇';
            app.Button_right = uibutton(app.UIFigure, 'state');
            app.Button_right.ValueChangedFcn = createCallbackFcn(app, @Button_rightValueChanged, true);
            app.Button_right.Text = '➞';
            app.Button_left = uibutton(app.UIFigure, 'state');
            app.Button_left.ValueChangedFcn = createCallbackFcn(app, @Button_leftValueChanged, true);
            app.Button_left.Text = '⬅';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end